classdef tubes_pswk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StopbandFrequencyradsEditField  matlab.ui.control.NumericEditField
        StopbandFrequencyradsEditFieldLabel  matlab.ui.control.Label
        PassbandFrequencyradsEditField  matlab.ui.control.NumericEditField
        PassbandFrequencyradsEditFieldLabel  matlab.ui.control.Label
        SubmitButton                    matlab.ui.control.Button
        TransferFunctionTextArea        matlab.ui.control.TextArea
        TransferFunctionLabel           matlab.ui.control.Label
        StopbandAttenuationdBEditField  matlab.ui.control.NumericEditField
        StopbandAttenuationdBEditFieldLabel  matlab.ui.control.Label
        PassbandAttenuationdBEditField  matlab.ui.control.NumericEditField
        PassbandAttenuationdBEditFieldLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            % Define HPF variables
            passAtt = app.PassbandAttenuationdBEditField.Value; % passband attenuation
            stopAtt = app.StopbandAttenuationdBEditField.Value; % stopband attenuation
            wp = app.PassbandFrequencyradsEditField.Value; % passband frequency
            ws = app.StopbandFrequencyradsEditField.Value; % stopband frequency

            % Get NLPF properties
            nwp = 1; % NLPF passband frequency
            nws = wp/ws; % NLPF stopband frequency

            % Calculate transfer function using Butterworth
            passTol = 10^(-passAtt/10); % (1-dp)^2
            stopTol = 10^(-stopAtt/10); % ds^2

            n = ceil((log10(sqrt((1/stopTol-1)/(1/passTol-1))))/log10(nws/nwp)) % filter order

            wc = nwp/((1/passTol-1)^(1/(2*n))); % cutoff frequency

            if mod(n, 2) == 0 % If order is even
                denom = [1]; % if n even, don't add anything
                for k = 1:n/2
                    b = 2*sin(((2*k-1)*pi)/(n*2)); % Butterworth constants
                    c = [1, b*wc, wc^2]; % Denominators (s^2 + bkwcs + wc^2)
                    denom = conv(denom, c);
                end
                
            else % If order is odd
                denom = [1, wc]; % if n odd, add (wc+1)
                for k = 1:(n-1)/2
                    b = 2*sin(((2*k-1)*pi)/(n*2)); % Butterworth constants
                    c = [1, b*wc, wc^2]; % Denominators (s^2 + bkwcs + wc^2)
                    denom = conv(denom, c);
                end
            end
            
            numer = [wc^n]; % Numerator of NLPF transfer function
            
            % Do Frequency Transformation to turn NLPF to HPF
            for z = 1:(n+1) % Multiply denominator by its corresponding wp
                denom(z) = denom(z)*wp^(n+1-z);
            end
            
            denomHpf = [];
            for z = 1:(n+1) % Reverse the position of the denominators
                denomHpf(z) = denom(n+2-z);
            end

            numerHpf = [];
            for z = 1:(n+1) % multiply numerator by s^n
                if z == 1
                    numerHpf(z) = numer(z);
                else
                    numerHpf(z) = 0;
                end
            end

            transfer_function = tf(numerHpf, denomHpf); % HPF Transfer Function
            
            % Display transfer function in app
            tfChar = evalc('transfer_function');
            tfCell = splitlines(tfChar);
            tfCell = tfCell(2:end-2);
            app.TransferFunctionTextArea.Value = string(tfCell);

            % Plot transfer function in app
            [mag, phase, wout] = bode(transfer_function);
            
            semilogx(app.UIAxes, wout, 20*log10(squeeze(mag)), 'LineWidth', 1);            
            semilogx(app.UIAxes2, wout, squeeze(phase), '-r', 'LineWidth', 1);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Magnituda')
            xlabel(app.UIAxes, 'Frequency (rad/s)')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Fasa')
            xlabel(app.UIAxes2, 'Frequency (rad/s)')
            ylabel(app.UIAxes2, 'Phase (deg)')
            zlabel(app.UIAxes2, 'Z')
            app.PassbandAttenuationdBEditFieldLabel = uilabel(app.UIFigure);
            app.PassbandAttenuationdBEditFieldLabel.Text = 'Passband Attenuation (dB)';
            app.PassbandAttenuationdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopbandAttenuationdBEditFieldLabel = uilabel(app.UIFigure);
            app.StopbandAttenuationdBEditFieldLabel.Text = 'Stopband Attenuation (dB)';
            app.StopbandAttenuationdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransferFunctionLabel = uilabel(app.UIFigure);
            app.TransferFunctionLabel.Text = {'Transfer'; 'Function'};
            app.TransferFunctionTextArea = uitextarea(app.UIFigure);
            app.TransferFunctionTextArea.Editable = 'off';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.PassbandFrequencyradsEditFieldLabel = uilabel(app.UIFigure);
            app.PassbandFrequencyradsEditFieldLabel.Text = 'Passband Frequency (rad/s)';
            app.PassbandFrequencyradsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopbandFrequencyradsEditFieldLabel = uilabel(app.UIFigure);
            app.StopbandFrequencyradsEditFieldLabel.Text = 'Stopband Frequency (rad/s)';
            app.StopbandFrequencyradsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tubes_pswk

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end